classdef Double_Stick_With_Slider_Torque_Controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlayingButton             matlab.ui.control.StateButton
        Slider                    matlab.ui.control.Slider
        ResetButton               matlab.ui.control.StateButton
        FrameButton               matlab.ui.control.Button
        PlaySpeedxEditFieldLabel  matlab.ui.control.Label
        PlaySpeedxEditField       matlab.ui.control.NumericEditField
        Take_OffButton            matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        l_Body double
        l_Leg double
        m_Body double
        m_Leg double
        g double
        
        l_Wrist_Bar double
        dl_Wrist_Bar double
        
        x_Wrist double
        dx_Wrist double
        y_Wrist double
        dy_Wrist double
        
        th_Wrist double
        dth_Wrist double
        th_Hip double
        dth_Hip double
        
        tau_Wrist double
        tau_Hip double
        
        status char
        
        time_step double
        
        stick_Body matlab.graphics.chart.primitive.Line
        stick_Leg matlab.graphics.chart.primitive.Line
        
        quivers matlab.graphics.chart.primitive.Quiver
        quiver_Ratio double
        
        tau_M_0_Ext = 1 % 正
        theta_A_0_Ext = 42
        theta_A_W_Ext = 70 % 正
        omega_Max_Ext = 526 % 正
        t_V_E_Max_Ext = 1.12
        t_V_C_Half_Ext = 0.25
        theta_PE_0_Ext = -0 % 負
        theta_PE_1_Ext = -90 % 負
        
        tau_M_0_Flex = -1 % 負 文献値と正負逆
        theta_A_0_Flex = 61 % 文献値と正負逆
        theta_A_W_Flex = 61 % 正
        omega_Max_Flex = -517 % 負 文献値と正負逆
        t_V_E_Max_Flex = 1.10
        t_V_C_Half_Flex = 0.16
        theta_PE_0_Flex = 0 % 正
        theta_PE_1_Flex = 150 % 正
        
        c = 1e1
    end
    
    methods (Access = private)
        
        function initialize_Data(app)
            app.status = 'Inair';
            
            app.l_Wrist_Bar = 0.5;
            app.dl_Wrist_Bar = 0;
            
            app.th_Wrist = 1/2 * pi;
            app.dth_Wrist = 0;
            
            app.th_Hip = 0/2 * pi;
            app.dth_Hip = 0;
            
            app.x_Wrist = -app.l_Wrist_Bar * cos(app.th_Wrist + 1/2 * pi);
            app.dx_Wrist = app.l_Wrist_Bar * app.dth_Wrist * sin(app.th_Wrist + 1/2 * pi) - app.dl_Wrist_Bar * cos(app.th_Wrist + 1/2 * pi);
            
            app.y_Wrist = -app.l_Wrist_Bar * sin(app.th_Wrist + 1/2 * pi) + 0.5;
            app.dy_Wrist = -app.l_Wrist_Bar * app.dth_Wrist * cos(app.th_Wrist + 1/2 * pi) - app.dl_Wrist_Bar * sin(app.th_Wrist + 1/2 * pi);
            
            %{
            app.status = 'Onbar';
            
            app.l_Wrist_Bar = 0;
            
            app.th_Wrist = 0/2 * pi;
            app.dth_Wrist = 0;
            
            app.th_Hip = 0/2 * pi;
            app.dth_Hip = 0;
            
            app.x_Wrist = -app.l_Wrist_Bar * cos(app.th_Wrist + 1/2 * pi);
            app.dx_Wrist = app.l_Wrist_Bar * app.dth_Wrist * sin(app.th_Wrist + 1/2 * pi);
            
            app.y_Wrist = -app.l_Wrist_Bar * sin(app.th_Wrist + 1/2 * pi);
            app.dy_Wrist = -app.l_Wrist_Bar * app.dth_Wrist * cos(app.th_Wrist + 1/2 * pi);
            %}
        end
        
        function refresh_Stick(app)
            
            x0 = app.x_Wrist;
            y0 = app.y_Wrist;
            x1 = x0 + app.l_Body * cos(app.th_Wrist + 1/2 * pi);
            y1 = y0 + app.l_Body * sin(app.th_Wrist + 1/2 * pi);
            x2 = x1 + app.l_Leg * cos(app.th_Wrist + 1/2 * pi + app.th_Hip);
            y2 = y1 + app.l_Leg * sin(app.th_Wrist + 1/2 * pi + app.th_Hip);
            
            app.stick_Body.XData(1) = x0;
            app.stick_Body.YData(1) = y0;
            app.stick_Body.XData(2) = x1;
            app.stick_Body.YData(2) = y1;
            
            app.stick_Leg.XData(1) = x1;
            app.stick_Leg.YData(1) = y1;
            app.stick_Leg.XData(2) = x2;
            app.stick_Leg.YData(2) = y2;
        end
        
        function refresh_Quivers(app)
            if isequal(app.status, 'Onbar')
                [f_X,f_Y] = find_F_Onbar(app.dth_Hip,app.dth_Wrist,app.g,app.l_Body,app.l_Leg,app.l_Wrist_Bar,app.m_Body,app.m_Leg,app.tau_Hip,app.th_Hip,app.th_Wrist);
            elseif isequal(app.status, 'Inair')
                f_X = 0;
                f_Y = 0;
            end
            app.quivers(1).UData = f_X / app.quiver_Ratio;
            app.quivers(1).VData = f_Y / app.quiver_Ratio;
        end
        
        function run_Ode(app)
            t = [0, app.time_step];
            
            if isequal(app.status, 'Onbar')
                q0 = [app.th_Wrist, app.dth_Wrist, app.th_Hip, app.dth_Hip]';
                [~, q] = ode45(@(t,q) ddt_Onbar(app, q), t, q0);
                %             [~, q] = ode45(@(t,q) ddt(app, q, app.l_Body, app.l_Leg, app.m_Body, app.m_Leg, app.g, app.tau_Wrist, app.tau_Hip), t, q0);
                
                app.th_Wrist = q(end,1);
                app.dth_Wrist = q(end,2);
                app.th_Hip = q(end,3);
                app.dth_Hip = q(end,4);
                
                app.x_Wrist = -app.l_Wrist_Bar * cos(app.th_Wrist + 1/2 * pi);
                app.dx_Wrist = app.l_Wrist_Bar * app.dth_Wrist * sin(app.th_Wrist + 1/2 * pi);
                app.y_Wrist = -app.l_Wrist_Bar * sin(app.th_Wrist + 1/2 * pi);
                app.dy_Wrist = -app.l_Wrist_Bar * app.dth_Wrist * cos(app.th_Wrist + 1/2 * pi);
            elseif isequal(app.status, 'Inair')
                q0 = [app.x_Wrist, app.dx_Wrist, app.y_Wrist, app.dy_Wrist, app.th_Wrist, app.dth_Wrist, app.th_Hip, app.dth_Hip]';
                ode_Event = @(t,q) event_Inair(app, q);
                ode_Options = odeset('Events', ode_Event);
                [~, q, ~, ~, ie] = ode45(@(t,q) ddt_Inair(app, q), t, q0, ode_Options);
                
                if ~isempty(ie)
                    if ie(end) == 1
                        app.status = 'Catch';
                    end
                end
                
                app.x_Wrist = q(end,1);
                app.dx_Wrist = q(end,2);
                app.y_Wrist = q(end,3);
                app.dy_Wrist = q(end,4);
                app.th_Wrist = q(end,5);
                app.dth_Wrist = q(end,6);
                app.th_Hip = q(end,7);
                app.dth_Hip = q(end,8);
            elseif isequal(app.status, 'Catch')
                
            else
                error('The status has not been determined')
            end
        end
        
        function dotq = ddt_Onbar(app, q)
            th_Wrist_Tmp = q(1);
            dth_Wrist_Tmp = q(2);
            th_Hip_Tmp = q(3);
            dth_Hip_Tmp = q(4);
            
            tau_Hip_Tmp = app.tau_Hip;
            
            if rad2deg(th_Hip_Tmp) < app.theta_PE_1_Ext
                if dth_Hip_Tmp < 0
                    tau_Hip_Tmp = tau_Hip_Tmp - app.c * dth_Hip_Tmp;
                end
            elseif rad2deg(th_Hip_Tmp) > app.theta_PE_1_Flex
                if dth_Hip_Tmp > 0
                    tau_Hip_Tmp = tau_Hip_Tmp - app.c * dth_Hip_Tmp;
                end
            end
            
            %{
            if rad2deg(th_Hip_Tmp) < app.theta_PE_0_Ext
                c_Ext = -1e-3;
                
                a_PE_Ext = (log(-c_Ext)-log(1-c_Ext))/(app.theta_PE_0_Ext - app.theta_PE_1_Ext);
                b_PE_Ext = -app.theta_PE_1_Ext + log(1 - c_Ext)/a_PE_Ext;
                
                t_PE_Ext = exp(a_PE_Ext * (rad2deg(th_Hip_Tmp) + b_PE_Ext)) + c_Ext;
                tau_Hip_Tmp = tau_Hip_Tmp + t_PE_Ext * app.tau_M_0_Ext;
            elseif rad2deg(th_Hip_Tmp) > app.theta_PE_0_Flex
                c_Flex = -1e-3;
                
                a_PE_Flex = (log(-c_Flex)-log(1 - c_Flex))/(app.theta_PE_0_Flex - app.theta_PE_1_Flex);
                b_PE_Flex = -app.theta_PE_1_Flex + log(1 - c_Flex)/a_PE_Flex;
                
                t_PE_Flex = exp(a_PE_Flex * (rad2deg(th_Hip_Tmp) + b_PE_Flex)) + c_Flex;
                tau_Hip_Tmp = tau_Hip_Tmp + t_PE_Flex * app.tau_M_0_Flex;
            end
            %}
            
            [ddth_Wrist,ddth_Hip] = find_dd_Onbar(dth_Hip_Tmp,dth_Wrist_Tmp,app.g,app.l_Body,app.l_Leg,app.l_Wrist_Bar,app.m_Body,app.m_Leg,tau_Hip_Tmp,th_Hip_Tmp,th_Wrist_Tmp);
            
            dotq = [dth_Wrist_Tmp, ddth_Wrist, dth_Hip_Tmp, ddth_Hip]';
        end
        
        function dotq = ddt_Inair(app, q)
            %             x_Wrist_Tmp = q(1);
            dx_Wrist_Tmp = q(2);
            %             y_Wrist_Tmp = q(3);
            dy_Wrist_Tmp = q(4);
            th_Wrist_Tmp = q(5);
            dth_Wrist_Tmp = q(6);
            th_Hip_Tmp = q(7);
            dth_Hip_Tmp = q(8);
            
            tau_Hip_Tmp = app.tau_Hip;
            
            if rad2deg(th_Hip_Tmp) < app.theta_PE_1_Ext
                if dth_Hip_Tmp < 0
                    tau_Hip_Tmp = tau_Hip_Tmp - app.c * dth_Hip_Tmp;
                end
            elseif rad2deg(th_Hip_Tmp) > app.theta_PE_1_Flex
                if dth_Hip_Tmp > 0
                    tau_Hip_Tmp = tau_Hip_Tmp - app.c * dth_Hip_Tmp;
                end
            end
            
            [ddx,ddy,ddth_Wrist,ddth_Hip] = find_dd_Inair(dth_Hip_Tmp,dth_Wrist_Tmp,app.g,app.l_Body,app.l_Leg,app.m_Body,app.m_Leg,tau_Hip_Tmp,th_Hip_Tmp,th_Wrist_Tmp);
            
            dotq = [dx_Wrist_Tmp, ddx, dy_Wrist_Tmp, ddy, dth_Wrist_Tmp, ddth_Wrist, dth_Hip_Tmp, ddth_Hip]';
        end
        
        function dotq = ddt_Catch(app, q)
            th_Wrist_Tmp = q(1);
            dth_Wrist_Tmp = q(2);
            th_Hip_Tmp = q(3);
            dth_Hip_Tmp = q(4);
            
            tau_Hip_Tmp = app.tau_Hip;
            
            if rad2deg(th_Hip_Tmp) < app.theta_PE_1_Ext
                if dth_Hip_Tmp < 0
                    tau_Hip_Tmp = tau_Hip_Tmp - app.c * dth_Hip_Tmp;
                end
            elseif rad2deg(th_Hip_Tmp) > app.theta_PE_1_Flex
                if dth_Hip_Tmp > 0
                    tau_Hip_Tmp = tau_Hip_Tmp - app.c * dth_Hip_Tmp;
                end
            end
            
            [ddl_Wrist_Bar,ddth_Wrist,ddth_Hip] = find_dd_Catch(dl_Wrist_Bar,dth_Hip,dth_Wrist,f_Wrist_Bar,g,l_Body,l_Leg,l_Wrist_Bar,m_Body,m_Leg,tau_Hip,th_Hip,th_Wrist);
            
            dotq = [dth_Wrist_Tmp, ddth_Wrist, dth_Hip_Tmp, ddth_Hip]';
        end
        
        
        function [value,isterminal,direction] = event_Inair(app, q)
            
            x_Wrist_Tmp = q(1);
            dx_Wrist_Tmp = q(2);
            y_Wrist_Tmp = q(3);
            dy_Wrist_Tmp = q(4);
            th_Wrist_Tmp = q(5);
            dth_Wrist_Tmp = q(6);
            th_Hip_Tmp = q(7);
            dth_Hip_Tmp = q(8);
            
            [x_Cross, y_Cross] = calc_Cross(app, th_Wrist_Tmp, x_Wrist_Tmp, y_Wrist_Tmp);
            
            p_Wrist_Tmp = [x_Wrist_Tmp, y_Wrist_Tmp];
            p_Hip_Tmp = x_Wrist_Tmp + app.l_Body * [cos(th_Wrist_Tmp + 1/2 * pi), sin(th_Wrist_Tmp + 1/2 * pi)];
            p_Cross = [x_Cross, y_Cross];
            
            value = min([vecnorm(p_Cross) * sign(p_Cross(2)), vecnorm(p_Wrist_Tmp) * sign(p_Wrist_Tmp(2)), vecnorm(p_Hip_Tmp) * sign(p_Hip_Tmp(2))]);
            direction = -1;
            isterminal = 1;
            
        end
        
        function [x_Cross, y_Cross]= calc_Cross(app, th_Wrist, x_Wrist, y_Wrist)
            theta_Tmp = th_Wrist + 1/2 * pi;
            x_Cross = sin(theta_Tmp)^2 * x_Wrist - sin(theta_Tmp) * cos(theta_Tmp) * y_Wrist;
            if nargout > 1
                y_Cross = -sin(theta_Tmp) * cos(theta_Tmp) * x_Wrist + cos(theta_Tmp)^2 * y_Wrist;
            end
        end
        
        function [ddth_Wrist,ddth_Hip] = find_dd_Onbar(dth_Hip,dth_Wrist,g,l_Body,l_Leg,l_Wrist_Bar,m_Body,m_Leg,tau_Hip,th_Hip,th_Wrist)
            
            t2 = cos(th_Hip);
            t3 = sin(th_Hip);
            t4 = sin(th_Wrist);
            t5 = th_Hip+th_Wrist;
            t6 = dth_Hip.^2;
            t7 = dth_Wrist.^2;
            t8 = l_Body.^2;
            t9 = l_Leg.^2;
            t10 = l_Wrist_Bar.^2;
            t11 = th_Hip.*2.0;
            t16 = 1.0./l_Leg;
            t20 = l_Body.*l_Wrist_Bar.*m_Body.*1.2e+1;
            t21 = l_Body.*l_Wrist_Bar.*m_Leg.*2.4e+1;
            t12 = cos(t11);
            t13 = t2.^2;
            t14 = sin(t11);
            t15 = sin(t5);
            t17 = t5+th_Hip;
            t19 = m_Body.*t8.*4.0;
            t22 = m_Leg.*t8.*1.2e+1;
            t23 = m_Body.*t10.*1.2e+1;
            t24 = m_Leg.*t10.*1.2e+1;
            t25 = -t20;
            t26 = -t21;
            t18 = sin(t17);
            ddth_Wrist = -(t16.*(l_Leg.*tau_Hip.*2.4e+1+l_Body.*t2.*tau_Hip.*3.6e+1-l_Wrist_Bar.*t2.*tau_Hip.*3.6e+1-g.*l_Body.*l_Leg.*m_Body.*t4.*1.2e+1-g.*l_Body.*l_Leg.*m_Leg.*t4.*1.5e+1+g.*l_Body.*l_Leg.*m_Leg.*t18.*9.0+g.*l_Leg.*l_Wrist_Bar.*m_Body.*t4.*2.4e+1+g.*l_Leg.*l_Wrist_Bar.*m_Leg.*t4.*1.5e+1-g.*l_Leg.*l_Wrist_Bar.*m_Leg.*t18.*9.0-l_Body.*m_Leg.*t3.*t6.*t9.*1.2e+1-l_Body.*m_Leg.*t3.*t7.*t9.*1.2e+1-l_Leg.*m_Leg.*t7.*t8.*t14.*9.0-l_Leg.*m_Leg.*t7.*t10.*t14.*9.0+l_Wrist_Bar.*m_Leg.*t3.*t6.*t9.*1.2e+1+l_Wrist_Bar.*m_Leg.*t3.*t7.*t9.*1.2e+1-dth_Hip.*dth_Wrist.*l_Body.*m_Leg.*t3.*t9.*2.4e+1+dth_Hip.*dth_Wrist.*l_Wrist_Bar.*m_Leg.*t3.*t9.*2.4e+1+l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t7.*t14.*1.8e+1))./(m_Body.*t8.*8.0+m_Body.*t10.*2.4e+1+m_Leg.*t8.*1.5e+1+m_Leg.*t10.*1.5e+1-l_Body.*l_Wrist_Bar.*m_Body.*2.4e+1-l_Body.*l_Wrist_Bar.*m_Leg.*3.0e+1-m_Leg.*t8.*t12.*9.0-m_Leg.*t10.*t12.*9.0+l_Body.*l_Wrist_Bar.*m_Leg.*t12.*1.8e+1);
            if nargout > 1
                t27 = l_Body.*l_Wrist_Bar.*m_Leg.*t13.*1.8e+1;
                t28 = m_Leg.*t8.*t13.*9.0;
                t29 = m_Leg.*t10.*t13.*9.0;
                t30 = -t28;
                t31 = -t29;
                t32 = t19+t22+t23+t24+t25+t26+t27+t30+t31;
                t33 = 1.0./t32;
                ddth_Hip = -t16.*t33.*(l_Leg.*2.0+l_Body.*t2.*3.0-l_Wrist_Bar.*t2.*3.0).*(g.*l_Body.*m_Body.*t4.*3.0+g.*l_Body.*m_Leg.*t4.*6.0+g.*l_Leg.*m_Leg.*t15.*3.0-g.*l_Wrist_Bar.*m_Body.*t4.*6.0-g.*l_Wrist_Bar.*m_Leg.*t4.*6.0+l_Body.*l_Leg.*m_Leg.*t3.*t6.*3.0-l_Leg.*l_Wrist_Bar.*m_Leg.*t3.*t6.*3.0+dth_Hip.*dth_Wrist.*l_Body.*l_Leg.*m_Leg.*t3.*6.0-dth_Hip.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*m_Leg.*t3.*6.0)+(t33.*(tau_Hip.*1.2e+1+g.*l_Leg.*m_Leg.*t15.*6.0-l_Body.*l_Leg.*m_Leg.*t3.*t7.*6.0+l_Leg.*l_Wrist_Bar.*m_Leg.*t3.*t7.*6.0).*(m_Body.*t8+m_Body.*t10.*3.0+m_Leg.*t8.*3.0+m_Leg.*t9+m_Leg.*t10.*3.0-l_Body.*l_Wrist_Bar.*m_Body.*3.0-l_Body.*l_Wrist_Bar.*m_Leg.*6.0+l_Body.*l_Leg.*m_Leg.*t2.*3.0-l_Leg.*l_Wrist_Bar.*m_Leg.*t2.*3.0))./(m_Leg.*t9);
            end
        end
        
        function [ddx,ddy,ddth_Wrist,ddth_Hip] = find_dd_Inair(dth_Hip,dth_Wrist,g,l_Body,l_Leg,m_Body,m_Leg,tau_Hip,th_Hip,th_Wrist)
            
            t2 = cos(th_Hip);
            t3 = cos(th_Wrist);
            t4 = sin(th_Hip);
            t5 = sin(th_Wrist);
            t6 = th_Hip+th_Wrist;
            t7 = dth_Hip.^2;
            t8 = dth_Wrist.^2;
            t9 = l_Body.^2;
            t10 = l_Body.^3;
            t11 = l_Leg.^2;
            t12 = l_Leg.^3;
            t13 = m_Body.^2;
            t14 = m_Body.^3;
            t15 = m_Leg.^2;
            t16 = m_Leg.^3;
            t17 = th_Hip.*2.0;
            t22 = 1.0./l_Body;
            t24 = 1.0./l_Leg;
            t25 = 1.0./m_Body;
            t26 = -th_Wrist;
            t27 = m_Body.*m_Leg.*2.5e+1;
            t18 = cos(t17);
            t19 = sin(t17);
            t20 = cos(t6);
            t21 = sin(t6);
            t23 = 1.0./t9;
            t28 = t6+th_Hip;
            t31 = t13.*8.0;
            t32 = t15.*8.0;
            t33 = t26+th_Hip;
            t29 = cos(t28);
            t30 = sin(t28);
            t34 = cos(t33);
            t35 = sin(t33);
            t36 = m_Body.*m_Leg.*t18.*9.0;
            t37 = -t36;
            t38 = t27+t31+t32+t37;
            t39 = 1.0./t38;
            ddx = -t22.*t24.*t25.*t39.*(l_Body.*t13.*t20.*tau_Hip.*6.0+l_Body.*t13.*t34.*tau_Hip.*1.8e+1+l_Leg.*t3.*t13.*tau_Hip.*4.8e+1+l_Leg.*t3.*t15.*tau_Hip.*2.4e+1-l_Body.*m_Body.*m_Leg.*t20.*tau_Hip.*1.2e+1+l_Body.*m_Body.*m_Leg.*t34.*tau_Hip.*3.6e+1+l_Leg.*m_Body.*m_Leg.*t3.*tau_Hip.*9.0e+1-l_Leg.*m_Body.*m_Leg.*t29.*tau_Hip.*1.8e+1+l_Leg.*t5.*t8.*t9.*t14.*4.0+l_Body.*m_Body.*t7.*t11.*t15.*t21+l_Body.*m_Body.*t8.*t11.*t15.*t21-l_Body.*m_Body.*t7.*t11.*t15.*t35.*3.0-l_Body.*m_Body.*t8.*t11.*t15.*t35.*3.0-l_Body.*m_Leg.*t7.*t11.*t13.*t21.*2.0-l_Body.*m_Leg.*t8.*t11.*t13.*t21.*2.0+l_Leg.*m_Body.*t5.*t8.*t9.*t32-l_Body.*m_Leg.*t7.*t11.*t13.*t35.*6.0-l_Body.*m_Leg.*t8.*t11.*t13.*t35.*6.0+l_Leg.*m_Leg.*t5.*t8.*t9.*t13.*1.5e+1-l_Leg.*m_Leg.*t8.*t9.*t13.*t30.*3.0+dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t11.*t15.*t21.*2.0-dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t11.*t15.*t35.*6.0-dth_Hip.*dth_Wrist.*l_Body.*m_Leg.*t11.*t13.*t21.*4.0-dth_Hip.*dth_Wrist.*l_Body.*m_Leg.*t11.*t13.*t35.*1.2e+1);
            if nargout > 1
                ddy = t22.*t24.*t25.*t39.*(g.*l_Body.*l_Leg.*t14.*-8.0-l_Body.*t13.*t21.*tau_Hip.*6.0+l_Body.*t13.*t35.*tau_Hip.*1.8e+1-l_Leg.*t5.*t13.*tau_Hip.*4.8e+1-l_Leg.*t5.*t15.*tau_Hip.*2.4e+1-g.*l_Body.*l_Leg.*m_Body.*t15.*8.0-g.*l_Body.*l_Leg.*m_Leg.*t13.*2.5e+1+l_Body.*m_Body.*m_Leg.*t21.*tau_Hip.*1.2e+1+l_Body.*m_Body.*m_Leg.*t35.*tau_Hip.*3.6e+1-l_Leg.*m_Body.*m_Leg.*t5.*tau_Hip.*9.0e+1+l_Leg.*m_Body.*m_Leg.*t30.*tau_Hip.*1.8e+1+l_Leg.*t3.*t8.*t9.*t14.*4.0+l_Body.*m_Body.*t7.*t11.*t15.*t20+l_Body.*m_Body.*t8.*t11.*t15.*t20+l_Body.*m_Body.*t7.*t11.*t15.*t34.*3.0+l_Body.*m_Body.*t8.*t11.*t15.*t34.*3.0-l_Body.*m_Leg.*t7.*t11.*t13.*t20.*2.0-l_Body.*m_Leg.*t8.*t11.*t13.*t20.*2.0+l_Leg.*m_Body.*t3.*t8.*t9.*t32+l_Body.*m_Leg.*t7.*t11.*t13.*t34.*6.0+l_Body.*m_Leg.*t8.*t11.*t13.*t34.*6.0+l_Leg.*m_Leg.*t3.*t8.*t9.*t13.*1.5e+1-l_Leg.*m_Leg.*t8.*t9.*t13.*t29.*3.0+g.*l_Body.*l_Leg.*m_Leg.*t13.*t18.*9.0+dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t11.*t15.*t20.*2.0+dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t11.*t15.*t34.*6.0-dth_Hip.*dth_Wrist.*l_Body.*m_Leg.*t11.*t13.*t20.*4.0+dth_Hip.*dth_Wrist.*l_Body.*m_Leg.*t11.*t13.*t34.*1.2e+1);
            end
            if nargout > 2
                ddth_Wrist = (t23.*t24.*t25.*(l_Leg.*t13.*tau_Hip.*-4.8e+1-l_Leg.*t15.*tau_Hip.*1.2e+1-l_Leg.*m_Body.*m_Leg.*tau_Hip.*6.0e+1-l_Body.*t2.*t13.*tau_Hip.*3.6e+1-l_Body.*m_Body.*m_Leg.*t2.*tau_Hip.*3.6e+1+l_Body.*m_Body.*t4.*t7.*t11.*t15.*3.0+l_Body.*m_Body.*t4.*t8.*t11.*t15.*3.0+l_Body.*m_Leg.*t4.*t7.*t11.*t13.*1.2e+1+l_Body.*m_Leg.*t4.*t8.*t11.*t13.*1.2e+1+l_Leg.*m_Leg.*t8.*t9.*t13.*t19.*(9.0./2.0)+dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t4.*t11.*t15.*6.0+dth_Hip.*dth_Wrist.*l_Body.*m_Leg.*t4.*t11.*t13.*2.4e+1))./(t13.*4.0+t15.*4.0+m_Body.*m_Leg.*1.7e+1-m_Body.*m_Leg.*t2.^2.*9.0);
            end
            if nargout > 3
                ddth_Hip = (t23.*t25.*t39.*(t9.*t14.*tau_Hip.*-1.2e+1-t11.*t16.*tau_Hip.*1.2e+1-m_Body.*t9.*t15.*tau_Hip.*4.8e+1-m_Body.*t11.*t15.*tau_Hip.*6.0e+1-m_Leg.*t9.*t13.*tau_Hip.*6.0e+1-m_Leg.*t11.*t13.*tau_Hip.*4.8e+1-l_Body.*l_Leg.*m_Body.*t2.*t15.*tau_Hip.*7.2e+1-l_Body.*l_Leg.*m_Leg.*t2.*t13.*tau_Hip.*7.2e+1+l_Body.*m_Body.*t4.*t7.*t12.*t16.*3.0+l_Body.*m_Body.*t4.*t8.*t12.*t16.*3.0+l_Leg.*m_Leg.*t4.*t8.*t10.*t14.*3.0+l_Body.*t4.*t7.*t12.*t13.*t15.*1.2e+1+l_Body.*t4.*t8.*t12.*t13.*t15.*1.2e+1+l_Leg.*t4.*t8.*t10.*t13.*t15.*1.2e+1+t7.*t9.*t11.*t13.*t15.*t19.*(9.0./2.0)+t8.*t9.*t11.*t13.*t15.*t19.*9.0+dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t4.*t12.*t16.*6.0+dth_Hip.*dth_Wrist.*l_Body.*t4.*t12.*t13.*t15.*2.4e+1+dth_Hip.*dth_Wrist.*t9.*t11.*t13.*t15.*t19.*9.0).*-2.0)./(m_Leg.*t11);
            end
        end
        
        function [ddl_Wrist_Bar,ddth_Wrist,ddth_Hip] = find_dd_Catch(dl_Wrist_Bar,dth_Hip,dth_Wrist,f_Wrist_Bar,g,l_Body,l_Leg,l_Wrist_Bar,m_Body,m_Leg,tau_Hip,th_Hip,th_Wrist)
            
            t2 = cos(th_Hip);
            t3 = cos(th_Wrist);
            t4 = sin(th_Hip);
            t5 = sin(th_Wrist);
            t6 = dth_Hip.^2;
            t7 = dth_Wrist.^2;
            t8 = l_Body.^2;
            t9 = l_Body.^3;
            t10 = l_Leg.^2;
            t11 = l_Leg.^3;
            t12 = l_Wrist_Bar.^2;
            t13 = l_Wrist_Bar.^3;
            t14 = m_Body.^2;
            t15 = m_Leg.^2;
            t16 = m_Leg.^3;
            t17 = th_Hip.*2.0;
            t21 = 1.0./l_Leg;
            t23 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*2.7e+1;
            t18 = cos(t17);
            t19 = t2.^2;
            t20 = sin(t17);
            ddl_Wrist_Bar = (t21.*(f_Wrist_Bar.*l_Leg.*m_Body.*t8.*-1.6e+1-f_Wrist_Bar.*l_Leg.*m_Body.*t12.*4.8e+1-f_Wrist_Bar.*l_Leg.*m_Leg.*t8.*3.0e+1-f_Wrist_Bar.*l_Leg.*m_Leg.*t12.*3.0e+1+l_Leg.*t7.*t9.*t14.*8.0+l_Leg.*t7.*t9.*t15.*1.2e+1-l_Leg.*t7.*t13.*t14.*4.8e+1-l_Leg.*t7.*t13.*t15.*1.2e+1+m_Body.*t4.*t8.*tau_Hip.*2.4e+1+m_Body.*t4.*t12.*tau_Hip.*7.2e+1+m_Leg.*t4.*t8.*tau_Hip.*7.2e+1+m_Leg.*t4.*t12.*tau_Hip.*7.2e+1+f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*4.8e+1+f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*6.0e+1+f_Wrist_Bar.*l_Leg.*m_Leg.*t8.*t18.*1.8e+1+f_Wrist_Bar.*l_Leg.*m_Leg.*t12.*t18.*1.8e+1+l_Body.*l_Leg.*m_Leg.*t20.*tau_Hip.*1.8e+1-l_Body.*l_Wrist_Bar.*m_Body.*t4.*tau_Hip.*7.2e+1-l_Body.*l_Wrist_Bar.*m_Leg.*t4.*tau_Hip.*1.44e+2-l_Leg.*l_Wrist_Bar.*m_Leg.*t20.*tau_Hip.*1.8e+1+l_Leg.*m_Body.*m_Leg.*t7.*t9.*2.5e+1-l_Leg.*m_Body.*m_Leg.*t7.*t13.*6.0e+1-g.*l_Leg.*t3.*t8.*t14.*1.6e+1-g.*l_Leg.*t3.*t8.*t15.*1.2e+1-g.*l_Leg.*t3.*t12.*t14.*4.8e+1-g.*l_Leg.*t3.*t12.*t15.*1.2e+1+l_Body.*l_Leg.*t7.*t12.*t14.*7.2e+1+l_Body.*l_Leg.*t7.*t12.*t15.*3.6e+1-l_Leg.*l_Wrist_Bar.*t7.*t8.*t14.*4.0e+1-l_Leg.*l_Wrist_Bar.*t7.*t8.*t15.*3.6e+1+t2.*t6.*t8.*t10.*t15.*6.0+t2.*t7.*t8.*t10.*t15.*6.0+t2.*t6.*t10.*t12.*t15.*6.0+t2.*t7.*t10.*t12.*t15.*6.0-l_Leg.*m_Body.*m_Leg.*t7.*t9.*t18.*3.0-l_Body.*l_Wrist_Bar.*t2.*t6.*t10.*t15.*1.2e+1-l_Body.*l_Wrist_Bar.*t2.*t7.*t10.*t15.*1.2e+1+m_Body.*m_Leg.*t2.*t6.*t8.*t10.*8.0+m_Body.*m_Leg.*t2.*t7.*t8.*t10.*8.0+m_Body.*m_Leg.*t2.*t6.*t10.*t12.*2.4e+1+m_Body.*m_Leg.*t2.*t7.*t10.*t12.*2.4e+1-f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t18.*3.6e+1+g.*l_Body.*l_Leg.*l_Wrist_Bar.*t3.*t14.*4.8e+1+g.*l_Body.*l_Leg.*l_Wrist_Bar.*t3.*t15.*2.4e+1-g.*l_Leg.*m_Body.*m_Leg.*t3.*t8.*4.0e+1-g.*l_Leg.*m_Body.*m_Leg.*t3.*t12.*6.0e+1+dth_Hip.*dth_Wrist.*t2.*t8.*t10.*t15.*1.2e+1+dth_Hip.*dth_Wrist.*t2.*t10.*t12.*t15.*1.2e+1+l_Body.*l_Leg.*m_Body.*m_Leg.*t7.*t12.*1.35e+2-l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t7.*t8.*1.0e+2-dl_Wrist_Bar.*dth_Wrist.*l_Leg.*m_Body.*m_Leg.*t8.*t20.*6.0+g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t3.*9.0e+1-dth_Hip.*dth_Wrist.*l_Body.*l_Wrist_Bar.*t2.*t10.*t15.*2.4e+1+dth_Hip.*dth_Wrist.*m_Body.*m_Leg.*t2.*t8.*t10.*1.6e+1+dth_Hip.*dth_Wrist.*m_Body.*m_Leg.*t2.*t10.*t12.*4.8e+1+g.*l_Leg.*m_Body.*m_Leg.*t3.*t8.*t18.*1.2e+1-g.*l_Leg.*m_Body.*m_Leg.*t5.*t8.*t20.*3.0-l_Body.*l_Leg.*m_Body.*m_Leg.*t7.*t12.*t18.*9.0-l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t2.*t6.*t10.*2.4e+1-l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t2.*t7.*t10.*2.4e+1+l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t7.*t8.*t18.*1.2e+1+dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t20.*1.8e+1-dth_Hip.*dth_Wrist.*l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t2.*t10.*4.8e+1-g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t3.*t18.*1.8e+1+g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t5.*t20.*9.0).*(-1.0./2.0))./(t8.*t14.*8.0+t8.*t15.*6.0+t12.*t14.*2.4e+1+t12.*t15.*6.0-l_Body.*l_Wrist_Bar.*t14.*2.4e+1-l_Body.*l_Wrist_Bar.*t15.*1.2e+1+m_Body.*m_Leg.*t8.*2.0e+1+m_Body.*m_Leg.*t12.*3.0e+1-l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*4.5e+1-m_Body.*m_Leg.*t8.*t18.*6.0+l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t18.*9.0);
            if nargout > 1
                t22 = l_Body.*l_Wrist_Bar.*t15.*6.0;
                t24 = l_Body.*l_Wrist_Bar.*t14.*1.2e+1;
                t26 = m_Body.*m_Leg.*t8.*1.3e+1;
                t27 = m_Body.*m_Leg.*t12.*1.5e+1;
                t28 = -t23;
                t29 = t8.*t14.*4.0;
                t30 = t8.*t15.*3.0;
                t31 = t12.*t15.*3.0;
                t33 = t12.*t14.*1.2e+1;
                t25 = -t22;
                t32 = -t24;
                t34 = m_Body.*m_Leg.*t8.*t19.*6.0;
                t35 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t19.*9.0;
                t36 = -t34;
                t37 = t25+t26+t27+t28+t29+t30+t31+t32+t33+t35+t36;
                t38 = 1.0./t37;
                ddth_Wrist = t21.*t38.*(l_Leg.*m_Body.*tau_Hip.*2.4e+1+l_Leg.*m_Leg.*tau_Hip.*6.0+l_Body.*m_Body.*t2.*tau_Hip.*3.6e+1+l_Body.*m_Leg.*t2.*tau_Hip.*3.6e+1+l_Leg.*m_Leg.*t19.*tau_Hip.*1.8e+1-l_Wrist_Bar.*m_Body.*t2.*tau_Hip.*3.6e+1-l_Wrist_Bar.*m_Leg.*t2.*tau_Hip.*3.6e+1-dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*t14.*2.4e+1-dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*t15.*1.2e+1+dl_Wrist_Bar.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*t14.*4.8e+1+dl_Wrist_Bar.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*t15.*1.2e+1-f_Wrist_Bar.*l_Body.*l_Leg.*m_Leg.*t20.*9.0+f_Wrist_Bar.*l_Leg.*l_Wrist_Bar.*m_Leg.*t20.*9.0-g.*l_Body.*l_Leg.*t5.*t14.*1.2e+1-g.*l_Body.*l_Leg.*t5.*t15.*6.0+g.*l_Leg.*l_Wrist_Bar.*t5.*t14.*2.4e+1+g.*l_Leg.*l_Wrist_Bar.*t5.*t15.*6.0-l_Body.*t4.*t6.*t10.*t15.*3.0-l_Body.*t4.*t7.*t10.*t15.*3.0+l_Wrist_Bar.*t4.*t6.*t10.*t15.*3.0+l_Wrist_Bar.*t4.*t7.*t10.*t15.*3.0-l_Body.*m_Body.*m_Leg.*t4.*t6.*t10.*1.2e+1-l_Body.*m_Body.*m_Leg.*t4.*t7.*t10.*1.2e+1-l_Leg.*m_Body.*m_Leg.*t7.*t8.*t20.*(9.0./2.0)+l_Wrist_Bar.*m_Body.*m_Leg.*t4.*t6.*t10.*1.2e+1+l_Wrist_Bar.*m_Body.*m_Leg.*t4.*t7.*t10.*1.2e+1-dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*m_Body.*m_Leg.*5.4e+1+dl_Wrist_Bar.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*6.0e+1-g.*l_Body.*l_Leg.*m_Body.*m_Leg.*t5.*2.7e+1+g.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t5.*3.0e+1-dth_Hip.*dth_Wrist.*l_Body.*t4.*t10.*t15.*6.0+dth_Hip.*dth_Wrist.*l_Wrist_Bar.*t4.*t10.*t15.*6.0+dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*m_Body.*m_Leg.*t19.*1.8e+1-dth_Hip.*dth_Wrist.*l_Body.*m_Body.*m_Leg.*t4.*t10.*2.4e+1+dth_Hip.*dth_Wrist.*l_Wrist_Bar.*m_Body.*m_Leg.*t4.*t10.*2.4e+1+g.*l_Body.*l_Leg.*m_Body.*m_Leg.*t5.*t19.*9.0+l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t7.*t20.*(9.0./2.0)).*(-1.0./2.0);
            end
            if nargout > 2
                ddth_Hip = (t38.*(t8.*t14.*tau_Hip.*2.4e+1+t8.*t15.*tau_Hip.*7.2e+1+t10.*t15.*tau_Hip.*6.0+t12.*t14.*tau_Hip.*7.2e+1+t12.*t15.*tau_Hip.*7.2e+1-l_Body.*l_Wrist_Bar.*t14.*tau_Hip.*7.2e+1-l_Body.*l_Wrist_Bar.*t15.*tau_Hip.*1.44e+2+m_Body.*m_Leg.*t8.*tau_Hip.*9.6e+1+m_Body.*m_Leg.*t10.*tau_Hip.*2.4e+1+m_Body.*m_Leg.*t12.*tau_Hip.*1.44e+2+t10.*t15.*t19.*tau_Hip.*1.8e+1-dl_Wrist_Bar.*dth_Wrist.*l_Body.*t10.*t16.*1.2e+1+dl_Wrist_Bar.*dth_Wrist.*l_Wrist_Bar.*t10.*t16.*1.2e+1-l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*tau_Hip.*2.16e+2-f_Wrist_Bar.*l_Body.*t10.*t15.*t20.*9.0-f_Wrist_Bar.*l_Leg.*t4.*t8.*t15.*3.6e+1-f_Wrist_Bar.*l_Leg.*t4.*t12.*t15.*3.6e+1+f_Wrist_Bar.*l_Wrist_Bar.*t10.*t15.*t20.*9.0-g.*l_Body.*t5.*t10.*t16.*6.0+g.*l_Wrist_Bar.*t5.*t10.*t16.*6.0+l_Body.*l_Leg.*t2.*t15.*tau_Hip.*7.2e+1-l_Leg.*l_Wrist_Bar.*t2.*t15.*tau_Hip.*7.2e+1-l_Body.*t4.*t6.*t11.*t16.*3.0-l_Body.*t4.*t7.*t11.*t16.*3.0+l_Wrist_Bar.*t4.*t6.*t11.*t16.*3.0+l_Wrist_Bar.*t4.*t7.*t11.*t16.*3.0-l_Body.*m_Body.*t4.*t6.*t11.*t15.*1.2e+1-l_Body.*m_Body.*t4.*t7.*t11.*t15.*1.2e+1-l_Leg.*m_Body.*t4.*t7.*t9.*t15.*1.8e+1-l_Leg.*m_Leg.*t4.*t7.*t9.*t14.*6.0+l_Wrist_Bar.*m_Body.*t4.*t6.*t11.*t15.*1.2e+1+l_Wrist_Bar.*m_Body.*t4.*t7.*t11.*t15.*1.2e+1-m_Body.*t6.*t8.*t10.*t15.*t20.*6.0-m_Body.*t7.*t8.*t10.*t15.*t20.*(2.1e+1./2.0)-dl_Wrist_Bar.*dth_Wrist.*l_Body.*m_Body.*t10.*t15.*5.4e+1-dl_Wrist_Bar.*dth_Wrist.*l_Body.*m_Leg.*t10.*t14.*2.4e+1+dl_Wrist_Bar.*dth_Wrist.*l_Wrist_Bar.*m_Body.*t10.*t15.*6.0e+1+dl_Wrist_Bar.*dth_Wrist.*l_Wrist_Bar.*m_Leg.*t10.*t14.*4.8e+1-dth_Hip.*dth_Wrist.*l_Body.*t4.*t11.*t16.*6.0+dth_Hip.*dth_Wrist.*l_Wrist_Bar.*t4.*t11.*t16.*6.0+f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*t4.*t15.*7.2e+1-f_Wrist_Bar.*l_Leg.*m_Body.*m_Leg.*t4.*t8.*1.2e+1-f_Wrist_Bar.*l_Leg.*m_Body.*m_Leg.*t4.*t12.*3.6e+1+l_Body.*l_Leg.*m_Body.*m_Leg.*t2.*tau_Hip.*7.2e+1-l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t2.*tau_Hip.*7.2e+1-g.*l_Body.*m_Body.*t5.*t10.*t15.*2.7e+1-g.*l_Body.*m_Leg.*t5.*t10.*t14.*1.2e+1+g.*l_Wrist_Bar.*m_Body.*t5.*t10.*t15.*3.0e+1+g.*l_Wrist_Bar.*m_Leg.*t5.*t10.*t14.*2.4e+1+f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t4.*3.6e+1+dl_Wrist_Bar.*dth_Wrist.*l_Body.*m_Body.*t10.*t15.*t19.*1.8e+1-dl_Wrist_Bar.*dth_Wrist.*l_Leg.*m_Body.*t2.*t8.*t15.*1.2e+1-dl_Wrist_Bar.*dth_Wrist.*l_Leg.*m_Leg.*t2.*t8.*t14.*1.2e+1-dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t4.*t11.*t15.*2.4e+1+dth_Hip.*dth_Wrist.*l_Wrist_Bar.*m_Body.*t4.*t11.*t15.*2.4e+1-dth_Hip.*dth_Wrist.*m_Body.*t8.*t10.*t15.*t20.*1.2e+1+g.*l_Body.*m_Body.*t5.*t10.*t15.*t19.*9.0-g.*l_Leg.*m_Body.*t2.*t5.*t8.*t15.*6.0-g.*l_Leg.*m_Leg.*t2.*t5.*t8.*t14.*6.0-l_Body.*l_Leg.*m_Body.*t4.*t7.*t12.*t15.*1.8e+1-l_Body.*l_Leg.*m_Leg.*t4.*t7.*t12.*t14.*1.8e+1+l_Body.*l_Wrist_Bar.*m_Body.*t6.*t10.*t15.*t20.*9.0+l_Body.*l_Wrist_Bar.*m_Body.*t7.*t10.*t15.*t20.*(2.7e+1./2.0)+l_Leg.*l_Wrist_Bar.*m_Body.*t4.*t7.*t8.*t15.*3.6e+1+l_Leg.*l_Wrist_Bar.*m_Leg.*t4.*t7.*t8.*t14.*1.8e+1+dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*t2.*t15.*3.6e+1+dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t2.*t14.*3.6e+1+dth_Hip.*dth_Wrist.*l_Body.*l_Wrist_Bar.*m_Body.*t10.*t15.*t20.*1.8e+1+g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*t2.*t5.*t15.*1.8e+1+g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t2.*t5.*t14.*1.8e+1))./(m_Leg.*t10.*2.0);
            end
        end
        
        function [f_X,f_Y] = find_F_Onbar(dth_Hip,dth_Wrist,g,l_Body,l_Leg,l_Wrist_Bar,m_Body,m_Leg,tau_Hip,th_Hip,th_Wrist)
            t2 = conj(tau_Hip);
            t3 = cos(th_Hip);
            t4 = sin(th_Hip);
            t5 = sin(th_Wrist);
            t6 = th_Hip+th_Wrist;
            t7 = dth_Hip.^2;
            t8 = dth_Wrist.^2;
            t9 = l_Leg.*2.0;
            t10 = l_Body.^2;
            t11 = l_Leg.^2;
            t12 = l_Wrist_Bar.^2;
            t13 = th_Hip.*2.0;
            t18 = 1.0./l_Leg;
            t20 = 1.0./m_Leg;
            t26 = l_Body.*l_Wrist_Bar.*m_Body.*3.0;
            t27 = l_Body.*l_Wrist_Bar.*m_Leg.*6.0;
            t29 = pi./2.0;
            t38 = l_Body.*l_Wrist_Bar.*m_Body.*1.2e+1;
            t39 = l_Body.*l_Wrist_Bar.*m_Body.*2.4e+1;
            t41 = l_Body.*l_Wrist_Bar.*m_Leg.*2.4e+1;
            t42 = l_Body.*l_Wrist_Bar.*m_Leg.*3.0e+1;
            t14 = cos(t13);
            t15 = t3.^2;
            t16 = sin(t13);
            t17 = sin(t6);
            t19 = 1.0./t11;
            t21 = t2.*1.2e+1;
            t22 = m_Body.*t10;
            t23 = m_Leg.*t11;
            t24 = l_Body.*t3.*3.0;
            t25 = l_Wrist_Bar.*t3.*3.0;
            t28 = t6+th_Hip;
            t32 = m_Leg.*t10.*3.0;
            t33 = m_Body.*t12.*3.0;
            t34 = m_Leg.*t12.*3.0;
            t35 = l_Leg.*t2.*2.4e+1;
            t37 = -t26;
            t40 = -t27;
            t45 = t29+th_Wrist;
            t46 = g.*l_Body.*m_Body.*t5.*3.0;
            t47 = g.*l_Body.*m_Leg.*t5.*6.0;
            t48 = g.*l_Wrist_Bar.*m_Body.*t5.*6.0;
            t49 = g.*l_Wrist_Bar.*m_Leg.*t5.*6.0;
            t51 = m_Leg.*t10.*1.2e+1;
            t52 = m_Leg.*t10.*1.5e+1;
            t53 = m_Body.*t12.*1.2e+1;
            t54 = m_Body.*t12.*2.4e+1;
            t55 = m_Leg.*t12.*1.2e+1;
            t56 = m_Leg.*t12.*1.5e+1;
            t57 = -t38;
            t58 = -t39;
            t59 = -t41;
            t60 = -t42;
            t61 = l_Body.*t2.*t3.*3.6e+1;
            t62 = l_Wrist_Bar.*t2.*t3.*3.6e+1;
            t63 = l_Leg.*l_Wrist_Bar.*m_Leg.*t3.*-3.0;
            t68 = t6+t29;
            t69 = dth_Hip.*dth_Wrist.*l_Body.*l_Leg.*m_Leg.*t4.*6.0;
            t70 = dth_Hip.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*m_Leg.*t4.*6.0;
            t76 = g.*l_Body.*l_Leg.*m_Body.*t5.*1.2e+1;
            t77 = g.*l_Body.*l_Leg.*m_Leg.*t5.*1.5e+1;
            t78 = g.*l_Leg.*l_Wrist_Bar.*m_Body.*t5.*2.4e+1;
            t79 = g.*l_Leg.*l_Wrist_Bar.*m_Leg.*t5.*1.5e+1;
            t80 = l_Body.*l_Leg.*m_Leg.*t4.*t7.*3.0;
            t81 = l_Body.*l_Leg.*m_Leg.*t4.*t8.*6.0;
            t82 = l_Leg.*l_Wrist_Bar.*m_Leg.*t4.*t7.*3.0;
            t83 = l_Leg.*l_Wrist_Bar.*m_Leg.*t4.*t8.*6.0;
            t30 = sin(t28);
            t31 = t22.*4.0;
            t36 = -t25;
            t43 = l_Leg.*m_Leg.*t24;
            t44 = l_Leg.*m_Leg.*t25;
            t50 = t22.*8.0;
            t64 = -t48;
            t65 = -t49;
            t66 = cos(t45);
            t67 = sin(t45);
            t71 = cos(t68);
            t72 = g.*l_Leg.*m_Leg.*t17.*3.0;
            t73 = g.*l_Leg.*m_Leg.*t17.*6.0;
            t74 = sin(t68);
            t75 = -t62;
            t84 = -t70;
            t85 = l_Body.*l_Wrist_Bar.*m_Leg.*t14.*1.8e+1;
            t86 = l_Body.*l_Wrist_Bar.*m_Leg.*t15.*1.8e+1;
            t87 = -t76;
            t88 = -t77;
            t89 = -t81;
            t90 = -t82;
            t91 = m_Leg.*t10.*t14.*9.0;
            t92 = m_Leg.*t12.*t14.*9.0;
            t93 = m_Leg.*t10.*t15.*9.0;
            t94 = m_Leg.*t12.*t15.*9.0;
            t95 = dth_Hip.*dth_Wrist.*l_Body.*t4.*t23.*2.4e+1;
            t96 = dth_Hip.*dth_Wrist.*l_Wrist_Bar.*t4.*t23.*2.4e+1;
            t103 = l_Body.*t4.*t7.*t23.*1.2e+1;
            t104 = l_Body.*t4.*t8.*t23.*1.2e+1;
            t105 = l_Wrist_Bar.*t4.*t7.*t23.*1.2e+1;
            t106 = l_Wrist_Bar.*t4.*t8.*t23.*1.2e+1;
            t108 = l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t8.*t16.*1.8e+1;
            t112 = l_Leg.*m_Leg.*t8.*t10.*t16.*9.0;
            t113 = l_Leg.*m_Leg.*t8.*t12.*t16.*9.0;
            t97 = -t91;
            t98 = -t92;
            t99 = -t93;
            t100 = -t94;
            t101 = g.*l_Body.*l_Leg.*m_Leg.*t30.*9.0;
            t102 = g.*l_Leg.*l_Wrist_Bar.*m_Leg.*t30.*9.0;
            t107 = -t95;
            t110 = -t103;
            t111 = -t104;
            t114 = t9+t24+t36;
            t115 = -t112;
            t116 = -t113;
            t117 = t21+t73+t83+t89;
            t118 = t22+t23+t32+t33+t34+t37+t40+t43+t63;
            t122 = t46+t47+t64+t65+t69+t72+t80+t84+t90;
            t109 = -t102;
            t119 = t31+t51+t53+t55+t57+t59+t86+t99+t100;
            t120 = t50+t52+t54+t56+t58+t60+t85+t97+t98;
            t121 = 1.0./t119;
            t123 = 1.0./t120;
            t127 = t35+t61+t75+t78+t79+t87+t88+t96+t101+t105+t106+t107+t108+t109+t110+t111+t115+t116;
            t124 = t18.*t114.*t121.*t122;
            t125 = t19.*t20.*t117.*t118.*t121;
            t126 = -t125;
            t128 = t124+t126;
            f_X = l_Body.*m_Body.*t8.*t66.*(-1.0./2.0)-l_Body.*m_Leg.*t8.*t66-(l_Leg.*m_Leg.*t7.*t71)./2.0-(l_Leg.*m_Leg.*t8.*t71)./2.0+(l_Leg.*m_Leg.*t74.*t128)./2.0+l_Wrist_Bar.*m_Body.*t8.*t66+l_Wrist_Bar.*m_Leg.*t8.*t66+(m_Leg.*t74.*t123.*t127)./2.0-dth_Hip.*dth_Wrist.*l_Leg.*m_Leg.*t71+(l_Body.*m_Body.*t18.*t67.*t123.*t127)./2.0+l_Body.*m_Leg.*t18.*t67.*t123.*t127-l_Wrist_Bar.*m_Body.*t18.*t67.*t123.*t127-l_Wrist_Bar.*m_Leg.*t18.*t67.*t123.*t127;
            if nargout > 1
                f_Y = g.*m_Body+g.*m_Leg-(l_Body.*m_Body.*t8.*t67)./2.0-l_Body.*m_Leg.*t8.*t67-(l_Leg.*m_Leg.*t7.*t74)./2.0-(l_Leg.*m_Leg.*t8.*t74)./2.0-(l_Leg.*m_Leg.*t71.*t128)./2.0+l_Wrist_Bar.*m_Body.*t8.*t67+l_Wrist_Bar.*m_Leg.*t8.*t67-(m_Leg.*t71.*t123.*t127)./2.0-dth_Hip.*dth_Wrist.*l_Leg.*m_Leg.*t74-(l_Body.*m_Body.*t18.*t66.*t123.*t127)./2.0-l_Body.*m_Leg.*t18.*t66.*t123.*t127+l_Wrist_Bar.*m_Body.*t18.*t66.*t123.*t127+l_Wrist_Bar.*m_Leg.*t18.*t66.*t123.*t127;
            end
        end
        
        function [f_X,f_Y] = find_F_Catch(dl_Wrist_Bar,dth_Hip,dth_Wrist,f_Wrist_Bar,g,l_Body,l_Leg,l_Wrist_Bar,m_Body,m_Leg,tau_Hip,th_Hip,th_Wrist)
            t2 = conj(tau_Hip);
            t3 = cos(th_Hip);
            t4 = cos(th_Wrist);
            t5 = sin(th_Hip);
            t6 = sin(th_Wrist);
            t7 = dth_Hip.^2;
            t8 = dth_Wrist.^2;
            t9 = l_Body.^2;
            t10 = l_Body.^3;
            t11 = l_Leg.^2;
            t12 = l_Leg.^3;
            t13 = l_Wrist_Bar.^2;
            t14 = l_Wrist_Bar.^3;
            t15 = m_Body.^2;
            t16 = m_Leg.^2;
            t17 = m_Leg.^3;
            t18 = th_Hip.*2.0;
            t22 = 1.0./l_Leg;
            t23 = pi./2.0;
            t27 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*2.7e+1;
            t28 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*4.5e+1;
            t40 = f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*4.8e+1;
            t41 = f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*6.0e+1;
            t59 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*m_Body.*m_Leg.*5.4e+1;
            t60 = dl_Wrist_Bar.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*6.0e+1;
            t19 = cos(t18);
            t20 = t3.^2;
            t21 = sin(t18);
            t24 = l_Leg.*m_Leg.*t2.*6.0;
            t25 = l_Body.*l_Wrist_Bar.*t16.*6.0;
            t26 = l_Leg.*m_Body.*t2.*2.4e+1;
            t29 = t23+th_Wrist;
            t30 = l_Body.*l_Wrist_Bar.*t15.*1.2e+1;
            t31 = l_Body.*l_Wrist_Bar.*t15.*2.4e+1;
            t33 = l_Body.*l_Wrist_Bar.*t16.*1.2e+1;
            t34 = m_Body.*m_Leg.*t9.*1.3e+1;
            t35 = m_Body.*m_Leg.*t9.*2.0e+1;
            t36 = m_Body.*m_Leg.*t13.*1.5e+1;
            t37 = m_Body.*m_Leg.*t13.*3.0e+1;
            t38 = -t27;
            t39 = -t28;
            t45 = t9.*t15.*4.0;
            t46 = t9.*t16.*3.0;
            t47 = t9.*t16.*6.0;
            t48 = t13.*t16.*3.0;
            t49 = t13.*t16.*6.0;
            t54 = f_Wrist_Bar.*l_Leg.*m_Body.*t9.*1.6e+1;
            t55 = f_Wrist_Bar.*l_Leg.*m_Leg.*t9.*3.0e+1;
            t56 = f_Wrist_Bar.*l_Leg.*m_Body.*t13.*4.8e+1;
            t57 = f_Wrist_Bar.*l_Leg.*m_Leg.*t13.*3.0e+1;
            t61 = l_Body.*m_Body.*t2.*t3.*3.6e+1;
            t62 = l_Body.*m_Leg.*t2.*t3.*3.6e+1;
            t63 = l_Wrist_Bar.*m_Body.*t2.*t3.*3.6e+1;
            t64 = l_Wrist_Bar.*m_Leg.*t2.*t3.*3.6e+1;
            t65 = t9.*t15.*8.0;
            t66 = t13.*t15.*1.2e+1;
            t67 = t13.*t15.*2.4e+1;
            t68 = g.*l_Body.*l_Leg.*t6.*t16.*6.0;
            t69 = g.*l_Leg.*l_Wrist_Bar.*t6.*t16.*6.0;
            t70 = g.*l_Body.*l_Leg.*m_Body.*m_Leg.*t6.*2.7e+1;
            t71 = g.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t6.*3.0e+1;
            t76 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*t15.*2.4e+1;
            t77 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*t16.*1.2e+1;
            t78 = dl_Wrist_Bar.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*t15.*4.8e+1;
            t79 = dl_Wrist_Bar.*dth_Wrist.*l_Leg.*l_Wrist_Bar.*t16.*1.2e+1;
            t80 = t2.*t11.*t16.*6.0;
            t81 = -t59;
            t82 = m_Body.*m_Leg.*t2.*t11.*2.4e+1;
            t85 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t2.*2.16e+2;
            t93 = g.*l_Body.*l_Leg.*t6.*t15.*1.2e+1;
            t95 = g.*l_Leg.*l_Wrist_Bar.*t6.*t15.*2.4e+1;
            t96 = m_Body.*t2.*t5.*t9.*2.4e+1;
            t98 = l_Body.*l_Wrist_Bar.*m_Body.*t2.*t5.*7.2e+1;
            t99 = l_Body.*l_Wrist_Bar.*m_Leg.*t2.*t5.*1.44e+2;
            t100 = f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t5.*3.6e+1;
            t103 = t2.*t9.*t15.*2.4e+1;
            t104 = l_Body.*l_Wrist_Bar.*t2.*t15.*7.2e+1;
            t105 = l_Body.*l_Wrist_Bar.*t2.*t16.*1.44e+2;
            t106 = m_Body.*m_Leg.*t2.*t9.*9.6e+1;
            t107 = m_Body.*m_Leg.*t2.*t13.*1.44e+2;
            t109 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*t11.*t17.*1.2e+1;
            t110 = dl_Wrist_Bar.*dth_Wrist.*l_Wrist_Bar.*t11.*t17.*1.2e+1;
            t111 = l_Leg.*m_Body.*m_Leg.*t8.*t10.*2.5e+1;
            t112 = l_Leg.*m_Body.*m_Leg.*t8.*t14.*6.0e+1;
            t117 = m_Leg.*t2.*t5.*t9.*7.2e+1;
            t118 = m_Body.*t2.*t5.*t13.*7.2e+1;
            t119 = m_Leg.*t2.*t5.*t13.*7.2e+1;
            t123 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*t4.*t15.*4.8e+1;
            t124 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*t4.*t16.*2.4e+1;
            t125 = g.*l_Leg.*m_Body.*m_Leg.*t4.*t9.*4.0e+1;
            t126 = g.*l_Leg.*m_Body.*m_Leg.*t4.*t13.*6.0e+1;
            t127 = g.*l_Body.*t6.*t11.*t17.*6.0;
            t128 = g.*l_Wrist_Bar.*t6.*t11.*t17.*6.0;
            t129 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t4.*9.0e+1;
            t130 = f_Wrist_Bar.*l_Leg.*m_Body.*m_Leg.*t5.*t9.*1.2e+1;
            t131 = f_Wrist_Bar.*l_Leg.*m_Body.*m_Leg.*t5.*t13.*3.6e+1;
            t132 = l_Body.*l_Leg.*m_Body.*m_Leg.*t2.*t3.*7.2e+1;
            t133 = l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t2.*t3.*7.2e+1;
            t136 = t2.*t9.*t16.*7.2e+1;
            t137 = t2.*t13.*t15.*7.2e+1;
            t138 = t2.*t13.*t16.*7.2e+1;
            t142 = dth_Hip.*dth_Wrist.*l_Body.*t5.*t11.*t16.*6.0;
            t143 = dth_Hip.*dth_Wrist.*l_Body.*t5.*t12.*t17.*6.0;
            t144 = dth_Hip.*dth_Wrist.*l_Wrist_Bar.*t5.*t11.*t16.*6.0;
            t145 = dth_Hip.*dth_Wrist.*l_Wrist_Bar.*t5.*t12.*t17.*6.0;
            t146 = dth_Hip.*dth_Wrist.*l_Body.*m_Body.*m_Leg.*t5.*t11.*2.4e+1;
            t147 = dth_Hip.*dth_Wrist.*l_Wrist_Bar.*m_Body.*m_Leg.*t5.*t11.*2.4e+1;
            t148 = l_Leg.*t8.*t10.*t15.*8.0;
            t149 = l_Leg.*t8.*t10.*t16.*1.2e+1;
            t150 = l_Leg.*t8.*t14.*t15.*4.8e+1;
            t151 = l_Leg.*t8.*t14.*t16.*1.2e+1;
            t154 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*m_Leg.*t11.*t15.*2.4e+1;
            t155 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*m_Body.*t11.*t16.*5.4e+1;
            t156 = dl_Wrist_Bar.*dth_Wrist.*l_Wrist_Bar.*m_Leg.*t11.*t15.*4.8e+1;
            t157 = dl_Wrist_Bar.*dth_Wrist.*l_Wrist_Bar.*m_Body.*t11.*t16.*6.0e+1;
            t160 = g.*l_Leg.*t4.*t9.*t15.*1.6e+1;
            t161 = g.*l_Leg.*t4.*t9.*t16.*1.2e+1;
            t162 = g.*l_Leg.*t4.*t13.*t15.*4.8e+1;
            t163 = g.*l_Leg.*t4.*t13.*t16.*1.2e+1;
            t167 = f_Wrist_Bar.*l_Leg.*t5.*t9.*t16.*3.6e+1;
            t168 = f_Wrist_Bar.*l_Leg.*t5.*t13.*t16.*3.6e+1;
            t170 = l_Body.*l_Leg.*t2.*t3.*t16.*7.2e+1;
            t171 = l_Leg.*l_Wrist_Bar.*t2.*t3.*t16.*7.2e+1;
            t172 = f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*t5.*t16.*7.2e+1;
            t177 = l_Body.*t5.*t7.*t11.*t16.*3.0;
            t178 = l_Body.*t5.*t7.*t12.*t17.*3.0;
            t179 = l_Body.*t5.*t8.*t11.*t16.*3.0;
            t180 = l_Body.*t5.*t8.*t12.*t17.*3.0;
            t181 = l_Wrist_Bar.*t5.*t7.*t11.*t16.*3.0;
            t182 = l_Wrist_Bar.*t5.*t7.*t12.*t17.*3.0;
            t183 = l_Wrist_Bar.*t5.*t8.*t11.*t16.*3.0;
            t184 = l_Wrist_Bar.*t5.*t8.*t12.*t17.*3.0;
            t187 = l_Body.*m_Body.*m_Leg.*t5.*t7.*t11.*1.2e+1;
            t188 = l_Body.*m_Body.*m_Leg.*t5.*t8.*t11.*1.2e+1;
            t189 = l_Wrist_Bar.*m_Body.*m_Leg.*t5.*t7.*t11.*1.2e+1;
            t190 = l_Wrist_Bar.*m_Body.*m_Leg.*t5.*t8.*t11.*1.2e+1;
            t191 = g.*l_Body.*m_Leg.*t6.*t11.*t15.*1.2e+1;
            t192 = g.*l_Body.*m_Body.*t6.*t11.*t16.*2.7e+1;
            t193 = g.*l_Wrist_Bar.*m_Leg.*t6.*t11.*t15.*2.4e+1;
            t194 = g.*l_Wrist_Bar.*m_Body.*t6.*t11.*t16.*3.0e+1;
            t198 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*t3.*t16.*3.6e+1;
            t199 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t3.*t15.*3.6e+1;
            t200 = dth_Hip.*dth_Wrist.*l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t3.*t11.*4.8e+1;
            t203 = l_Leg.*l_Wrist_Bar.*t8.*t9.*t15.*4.0e+1;
            t204 = l_Body.*l_Leg.*t8.*t13.*t16.*3.6e+1;
            t205 = l_Leg.*l_Wrist_Bar.*t8.*t9.*t16.*3.6e+1;
            t209 = l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t8.*t9.*1.0e+2;
            t210 = l_Body.*l_Leg.*m_Body.*m_Leg.*t8.*t13.*1.35e+2;
            t218 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*t3.*t6.*t16.*1.8e+1;
            t219 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t3.*t6.*t15.*1.8e+1;
            t234 = dth_Hip.*dth_Wrist.*l_Body.*l_Wrist_Bar.*t3.*t11.*t16.*2.4e+1;
            t235 = dl_Wrist_Bar.*dth_Wrist.*l_Leg.*m_Body.*t3.*t9.*t16.*1.2e+1;
            t236 = dl_Wrist_Bar.*dth_Wrist.*l_Leg.*m_Leg.*t3.*t9.*t15.*1.2e+1;
            t237 = dth_Hip.*dth_Wrist.*m_Body.*m_Leg.*t3.*t9.*t11.*1.6e+1;
            t238 = dth_Hip.*dth_Wrist.*m_Body.*m_Leg.*t3.*t11.*t13.*4.8e+1;
            t239 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t3.*t7.*t11.*2.4e+1;
            t240 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t3.*t8.*t11.*2.4e+1;
            t241 = l_Leg.*m_Leg.*t5.*t8.*t10.*t15.*6.0;
            t244 = dth_Hip.*dth_Wrist.*l_Body.*m_Body.*t5.*t12.*t16.*2.4e+1;
            t245 = dth_Hip.*dth_Wrist.*l_Wrist_Bar.*m_Body.*t5.*t12.*t16.*2.4e+1;
            t247 = l_Body.*l_Leg.*t8.*t13.*t15.*7.2e+1;
            t252 = g.*l_Leg.*m_Leg.*t3.*t6.*t9.*t15.*6.0;
            t259 = dth_Hip.*dth_Wrist.*t3.*t9.*t11.*t16.*1.2e+1;
            t260 = dth_Hip.*dth_Wrist.*t3.*t11.*t13.*t16.*1.2e+1;
            t264 = m_Body.*m_Leg.*t3.*t7.*t9.*t11.*8.0;
            t265 = m_Body.*m_Leg.*t3.*t8.*t9.*t11.*8.0;
            t266 = m_Body.*m_Leg.*t3.*t7.*t11.*t13.*2.4e+1;
            t267 = m_Body.*m_Leg.*t3.*t8.*t11.*t13.*2.4e+1;
            t273 = l_Body.*m_Body.*t5.*t7.*t12.*t16.*1.2e+1;
            t275 = l_Body.*m_Body.*t5.*t8.*t12.*t16.*1.2e+1;
            t276 = l_Leg.*m_Body.*t5.*t8.*t10.*t16.*1.8e+1;
            t277 = l_Wrist_Bar.*m_Body.*t5.*t7.*t12.*t16.*1.2e+1;
            t278 = l_Wrist_Bar.*m_Body.*t5.*t8.*t12.*t16.*1.2e+1;
            t282 = g.*l_Leg.*m_Body.*t3.*t6.*t9.*t16.*-6.0;
            t284 = l_Body.*l_Wrist_Bar.*t3.*t7.*t11.*t16.*-1.2e+1;
            t285 = l_Body.*l_Wrist_Bar.*t3.*t8.*t11.*t16.*-1.2e+1;
            t293 = l_Body.*l_Leg.*m_Body.*t5.*t8.*t13.*t16.*1.8e+1;
            t294 = l_Body.*l_Leg.*m_Leg.*t5.*t8.*t13.*t15.*1.8e+1;
            t295 = l_Leg.*l_Wrist_Bar.*m_Leg.*t5.*t8.*t9.*t15.*1.8e+1;
            t32 = -t25;
            t42 = cos(t29);
            t43 = sin(t29);
            t44 = t29+th_Hip;
            t50 = -t30;
            t51 = -t31;
            t52 = -t33;
            t72 = -t54;
            t73 = -t55;
            t74 = -t56;
            t75 = -t57;
            t83 = -t63;
            t84 = -t64;
            t86 = m_Body.*m_Leg.*t9.*t19.*6.0;
            t87 = m_Body.*m_Leg.*t9.*t20.*6.0;
            t88 = l_Leg.*m_Leg.*t2.*t20.*1.8e+1;
            t89 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t19.*9.0;
            t90 = l_Body.*l_Wrist_Bar.*m_Body.*m_Leg.*t20.*9.0;
            t91 = f_Wrist_Bar.*l_Body.*l_Leg.*m_Leg.*t21.*9.0;
            t92 = f_Wrist_Bar.*l_Leg.*l_Wrist_Bar.*m_Leg.*t21.*9.0;
            t94 = -t68;
            t97 = -t70;
            t101 = -t76;
            t102 = -t77;
            t108 = -t85;
            t116 = -t93;
            t120 = -t98;
            t121 = -t99;
            t122 = f_Wrist_Bar.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Leg.*t19.*3.6e+1;
            t134 = l_Body.*l_Leg.*m_Leg.*t2.*t21.*1.8e+1;
            t135 = l_Leg.*l_Wrist_Bar.*m_Leg.*t2.*t21.*1.8e+1;
            t139 = -t104;
            t140 = -t105;
            t141 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*m_Body.*m_Leg.*t20.*1.8e+1;
            t152 = -t109;
            t153 = -t112;
            t158 = f_Wrist_Bar.*l_Leg.*m_Leg.*t9.*t19.*1.8e+1;
            t159 = f_Wrist_Bar.*l_Leg.*m_Leg.*t13.*t19.*1.8e+1;
            t165 = -t125;
            t166 = -t126;
            t169 = -t127;
            t173 = -t130;
            t174 = -t131;
            t175 = -t133;
            t185 = -t142;
            t186 = -t143;
            t195 = -t146;
            t196 = -t150;
            t197 = -t151;
            t201 = dl_Wrist_Bar.*dth_Wrist.*l_Leg.*m_Body.*m_Leg.*t9.*t21.*6.0;
            t202 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t21.*1.8e+1;
            t206 = g.*l_Body.*l_Leg.*m_Body.*m_Leg.*t6.*t20.*9.0;
            t207 = -t154;
            t208 = -t155;
            t211 = -t160;
            t212 = -t161;
            t213 = -t162;
            t214 = -t163;
            t215 = -t167;
            t216 = -t168;
            t217 = -t171;
            t220 = t2.*t11.*t16.*t20.*1.8e+1;
            t221 = g.*l_Leg.*m_Body.*m_Leg.*t6.*t9.*t21.*3.0;
            t222 = -t177;
            t223 = -t178;
            t224 = -t179;
            t225 = -t180;
            t226 = f_Wrist_Bar.*l_Body.*t11.*t16.*t21.*9.0;
            t227 = f_Wrist_Bar.*l_Wrist_Bar.*t11.*t16.*t21.*9.0;
            t228 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t6.*t21.*9.0;
            t229 = -t187;
            t230 = -t188;
            t231 = -t191;
            t232 = -t192;
            t233 = l_Leg.*m_Body.*m_Leg.*t8.*t10.*t19.*3.0;
            t242 = -t200;
            t246 = -t203;
            t248 = -t205;
            t249 = -t209;
            t250 = g.*l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t4.*t19.*1.8e+1;
            t251 = g.*l_Leg.*m_Body.*t3.*t6.*t47;
            t255 = t3.*t7.*t11.*t47;
            t256 = t3.*t8.*t11.*t47;
            t257 = t3.*t7.*t11.*t49;
            t258 = t3.*t8.*t11.*t49;
            t261 = t3.*t7.*t11.*t33;
            t262 = t3.*t8.*t11.*t33;
            t268 = -t234;
            t269 = -t235;
            t270 = -t236;
            t271 = -t239;
            t272 = -t240;
            t274 = -t241;
            t279 = -t244;
            t280 = g.*l_Leg.*m_Body.*m_Leg.*t4.*t9.*t19.*1.2e+1;
            t283 = -t252;
            t286 = -t273;
            t287 = -t275;
            t288 = -t276;
            t289 = l_Body.*l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t8.*t21.*(9.0./2.0);
            t290 = dl_Wrist_Bar.*dth_Wrist.*l_Body.*m_Body.*t11.*t16.*t20.*1.8e+1;
            t291 = l_Body.*l_Leg.*m_Body.*m_Leg.*t8.*t13.*t19.*9.0;
            t292 = l_Leg.*l_Wrist_Bar.*m_Body.*m_Leg.*t8.*t9.*t19.*1.2e+1;
            t296 = m_Body.*t5.*t205;
            t297 = dth_Hip.*dth_Wrist.*l_Body.*l_Wrist_Bar.*m_Body.*t11.*t16.*t21.*1.8e+1;
            t298 = g.*l_Body.*m_Body.*t6.*t11.*t16.*t20.*9.0;
            t299 = l_Leg.*m_Body.*m_Leg.*t8.*t9.*t21.*(9.0./2.0);
            t301 = -t293;
            t302 = -t294;
            t303 = m_Body.*t7.*t11.*t21.*t47;
            t304 = dth_Hip.*dth_Wrist.*m_Body.*t9.*t11.*t16.*t21.*1.2e+1;
            t305 = l_Body.*l_Wrist_Bar.*m_Body.*t7.*t11.*t16.*t21.*9.0;
            t307 = m_Body.*t7.*t9.*t11.*t16.*t21.*-6.0;
            t309 = l_Body.*l_Wrist_Bar.*m_Body.*t8.*t11.*t16.*t21.*(2.7e+1./2.0);
            t310 = m_Body.*t8.*t9.*t11.*t16.*t21.*(2.1e+1./2.0);
            t53 = cos(t44);
            t58 = sin(t44);
            t113 = -t86;
            t114 = -t87;
            t115 = -t91;
            t164 = -t122;
            t176 = -t135;
            t243 = -t201;
            t253 = -t221;
            t254 = -t226;
            t263 = -t233;
            t281 = -t250;
            t300 = -t291;
            t306 = -t299;
            t308 = -t304;
            t311 = -t310;
            t312 = t32+t34+t36+t38+t45+t46+t48+t50+t66+t90+t114;
            t313 = t35+t37+t39+t47+t49+t51+t52+t65+t67+t89+t113;
            t316 = t24+t26+t60+t61+t62+t69+t71+t78+t79+t81+t83+t84+t88+t92+t94+t95+t97+t101+t102+t115+t116+t141+t144+t147+t181+t183+t185+t189+t190+t195+t206+t222+t224+t229+t230+t289+t306;
            t317 = t40+t41+t72+t73+t74+t75+t96+t111+t117+t118+t119+t120+t121+t123+t124+t129+t134+t148+t149+t153+t158+t159+t164+t165+t166+t176+t196+t197+t202+t204+t210+t211+t212+t213+t214+t228+t237+t238+t242+t243+t246+t247+t248+t249+t253+t255+t256+t257+t258+t259+t260+t263+t264+t265+t266+t267+t268+t271+t272+t280+t281+t284+t285+t292+t300;
            t318 = t80+t82+t100+t103+t106+t107+t108+t110+t128+t132+t136+t137+t138+t139+t140+t145+t152+t156+t157+t169+t170+t172+t173+t174+t175+t182+t184+t186+t193+t194+t198+t199+t207+t208+t215+t216+t217+t218+t219+t220+t223+t225+t227+t231+t232+t245+t254+t269+t270+t274+t277+t278+t279+t282+t283+t286+t287+t288+t290+t295+t296+t297+t298+t301+t302+t305+t307+t308+t309+t311;
            t314 = 1.0./t312;
            t315 = 1.0./t313;
            f_X = dl_Wrist_Bar.*dth_Wrist.*m_Body.*t43.*2.0+dl_Wrist_Bar.*dth_Wrist.*m_Leg.*t43.*2.0-(l_Body.*m_Body.*t8.*t42)./2.0-l_Body.*m_Leg.*t8.*t42-(l_Leg.*m_Leg.*t7.*t53)./2.0-(l_Leg.*m_Leg.*t8.*t53)./2.0+l_Wrist_Bar.*m_Body.*t8.*t42+l_Wrist_Bar.*m_Leg.*t8.*t42+(m_Leg.*t58.*t314.*t316)./4.0-(t22.*t58.*t314.*t318)./4.0-dth_Hip.*dth_Wrist.*l_Leg.*m_Leg.*t53+(m_Body.*t22.*t42.*t315.*t317)./2.0+(m_Leg.*t22.*t42.*t315.*t317)./2.0+(l_Body.*m_Body.*t22.*t43.*t314.*t316)./4.0+(l_Body.*m_Leg.*t22.*t43.*t314.*t316)./2.0-(l_Wrist_Bar.*m_Body.*t22.*t43.*t314.*t316)./2.0-(l_Wrist_Bar.*m_Leg.*t22.*t43.*t314.*t316)./2.0;
            if nargout > 1
                f_Y = g.*m_Body+g.*m_Leg-dl_Wrist_Bar.*dth_Wrist.*m_Body.*t42.*2.0-dl_Wrist_Bar.*dth_Wrist.*m_Leg.*t42.*2.0-(l_Body.*m_Body.*t8.*t43)./2.0-l_Body.*m_Leg.*t8.*t43-(l_Leg.*m_Leg.*t7.*t58)./2.0-(l_Leg.*m_Leg.*t8.*t58)./2.0+l_Wrist_Bar.*m_Body.*t8.*t43+l_Wrist_Bar.*m_Leg.*t8.*t43-(m_Leg.*t53.*t314.*t316)./4.0+(t22.*t53.*t314.*t318)./4.0-dth_Hip.*dth_Wrist.*l_Leg.*m_Leg.*t58+(m_Body.*t22.*t43.*t315.*t317)./2.0+(m_Leg.*t22.*t43.*t315.*t317)./2.0-(l_Body.*m_Body.*t22.*t42.*t314.*t316)./4.0-(l_Body.*m_Leg.*t22.*t42.*t314.*t316)./2.0+(l_Wrist_Bar.*m_Body.*t22.*t42.*t314.*t316)./2.0+(l_Wrist_Bar.*m_Leg.*t22.*t42.*t314.*t316)./2.0;
            end
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.l_Wrist_Bar = 0;
            app.l_Body = 1;
            app.m_Body = 2;
            app.l_Leg = 1;
            app.m_Leg = 2;
            
            app.g = 1;
            app.time_step = 0.05;
            
            initialize_Data(app)
            
            app.tau_Wrist = 0;
            app.tau_Hip = 0;
            
            app.quiver_Ratio = 4;
            
            app.stick_Body = plot(app.UIAxes, ...
                [app.x_Wrist, app.x_Wrist + app.l_Body * cos(app.th_Wrist + 1/2 * pi)], ...
                [app.y_Wrist, app.y_Wrist + app.l_Body * sin(app.th_Wrist + 1/2 * pi)], ...
                '-', 'LineWidth', 3);
            
            hold(app.UIAxes, "on")
            app.stick_Leg = plot(app.UIAxes, ...
                [app.stick_Body.XData(end), app.stick_Body.XData(end) + app.l_Leg * cos(app.th_Wrist + 1/2 * pi + app.th_Hip)], ...
                [app.stick_Body.YData(end), app.stick_Body.YData(end) + app.l_Leg * sin(app.th_Wrist + 1/2 * pi + app.th_Hip)], ...
                '-', 'LineWidth', 3);
            hold(app.UIAxes, "off")
            
            xlim(app.UIAxes, [-(app.l_Body + app.l_Leg), (app.l_Body + app.l_Leg) * 3])
            ylim(app.UIAxes, [-(app.l_Body + app.l_Leg), (app.l_Body + app.l_Leg) * 1])
            %             daspect(app.UIAxes, [1,1,1])
            
            hold(app.UIAxes, "on")
            app.quivers(1,1) = quiver(app.UIAxes, 0, 0, 1, 1);
            hold(app.UIAxes, "off")
            refresh_Quivers(app)
            
            hold(app.UIAxes, "on")
            scatter(app.UIAxes, 0,0,[],"black","filled")
            hold(app.UIAxes, "off")
        end

        % Value changed function: PlayingButton
        function PlayingButtonValueChanged(app, event)
            value = app.PlayingButton.Value;
            if value
                while true
                    
                    run_Ode(app)
                    
                    refresh_Stick(app)
                    refresh_Quivers(app)
                    
                    pause(app.time_step / app.PlaySpeedxEditField.Value)
                    drawnow
                    
                    if ~app.PlayingButton.Value
                        app.ResetButton.Value = false;
                        break
                    end
                end
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.tau_Hip = changingValue;
            refresh_Quivers(app)
            drawnow
        end

        % Value changed function: ResetButton
        function ResetButtonValueChanged(app, event)
            value = app.ResetButton.Value;
            if value
                if app.PlayingButton.Value
                    app.PlayingButton.Value = false;
                else
                    app.ResetButton.Value = false;
                end
                
                initialize_Data(app)
                
                refresh_Stick(app)
                refresh_Quivers(app)
                drawnow
            end
        end

        % Button pushed function: FrameButton
        function FrameButtonPushed(app, event)
            if ~app.PlayingButton.Value
                run_Ode(app)
                
                refresh_Stick(app)
                refresh_Quivers(app)
                
                pause(app.time_step/1.2)
                drawnow
            end
        end

        % Button pushed function: Take_OffButton
        function Take_OffButtonPushed(app, event)
            app.status = 'Inair';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlayingButton = uibutton(app.UIFigure, 'state');
            app.PlayingButton.ValueChangedFcn = createCallbackFcn(app, @PlayingButtonValueChanged, true);
            app.PlayingButton.Text = 'Playing';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [-1 1];
            app.Slider.MajorTicks = [-1 -0.5 0 0.5 1];
            app.Slider.MajorTickLabels = {'-1', '-0.5', '0', '0.5', '1'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [-1 -0.96 -0.92 -0.88 -0.84 -0.8 -0.76 -0.72 -0.68 -0.64 -0.6 -0.56 -0.52 -0.48 -0.44 -0.4 -0.36 -0.32 -0.28 -0.24 -0.2 -0.16 -0.12 -0.08 -0.04 0 0.04 0.0800000000000001 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.ResetButton = uibutton(app.UIFigure, 'state');
            app.ResetButton.ValueChangedFcn = createCallbackFcn(app, @ResetButtonValueChanged, true);
            app.ResetButton.Text = 'Reset';
            app.FrameButton = uibutton(app.UIFigure, 'push');
            app.FrameButton.ButtonPushedFcn = createCallbackFcn(app, @FrameButtonPushed, true);
            app.FrameButton.Text = '1 Frame';
            app.PlaySpeedxEditFieldLabel = uilabel(app.UIFigure);
            app.PlaySpeedxEditFieldLabel.Text = 'Play Speed x';
            app.PlaySpeedxEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlaySpeedxEditField.Limits = [1e-06 Inf];
            app.PlaySpeedxEditField.Value = 0.3;
            app.Take_OffButton = uibutton(app.UIFigure, 'push');
            app.Take_OffButton.ButtonPushedFcn = createCallbackFcn(app, @Take_OffButtonPushed, true);
            app.Take_OffButton.Text = 'Take_Off';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 2];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Double_Stick_With_Slider_Torque_Controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end